classdef AxisLen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AxisLengthInfo       matlab.ui.Figure
        AxisLengthInfoLabel  matlab.ui.control.Label
        WhatisthelengthofthelinkalongthegivenaxisEditFieldLabel  matlab.ui.control.Label
        Length               matlab.ui.control.NumericEditField
        EditFieldLabel       matlab.ui.control.Label
        TwistAngle           matlab.ui.control.NumericEditField
        OKButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp2
        choice
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, RobotInfoapp, choice)
            app.CallingApp2=RobotInfoapp;
            app.choice.Value=choice;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.choice.Value == 1
                updateLenAgles(app.CallingApp2, 0, app.Length.Value, app.TwistAngle.Value, 0);
            else
                updateLenAgles(app.CallingApp2, app.Length.Value, 0, 0, app.TwistAngle.Value);
            end
            delete(app);
        end

        % Close request function: AxisLengthInfo
        function AxisLengthInfoCloseRequest(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AxisLengthInfo = uifigure('Visible', 'off');
            app.AxisLengthInfo.Name = 'Axis Length Info';
            app.AxisLengthInfo.CloseRequestFcn = createCallbackFcn(app, @AxisLengthInfoCloseRequest, true);
            app.AxisLengthInfoLabel = uilabel(app.AxisLengthInfo);
            app.AxisLengthInfoLabel.Text = 'Axis Length Info';
            app.WhatisthelengthofthelinkalongthegivenaxisEditFieldLabel = uilabel(app.AxisLengthInfo);
            app.WhatisthelengthofthelinkalongthegivenaxisEditFieldLabel.Text = 'What is the length of the link along the given axis?';
            app.Length = uieditfield(app.AxisLengthInfo, 'numeric');
            app.EditFieldLabel = uilabel(app.AxisLengthInfo);
            app.EditFieldLabel.Text = {'Is there an angle of twist present along the axis?'; 'Please enter the value in degrees if so (otherwise set to 0)'};
            app.TwistAngle = uieditfield(app.AxisLengthInfo, 'numeric');
            app.OKButton = uibutton(app.AxisLengthInfo, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.AxisLengthInfo.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AxisLen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AxisLengthInfo)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AxisLengthInfo)
        end
    end
end