classdef Controls_noDH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotionControl                   matlab.ui.Figure
        MotionControlLabel              matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        HowmanylinkswillbeinyourrobotEditFieldLabel  matlab.ui.control.Label
        Howmanylinks                    matlab.ui.control.NumericEditField
        GravVectorText                  matlab.ui.control.Label
        UpdateGravityVectorButton       matlab.ui.control.Button
        gxEditFieldLabel                matlab.ui.control.Label
        gx                              matlab.ui.control.NumericEditField
        gyEditFieldLabel                matlab.ui.control.Label
        gy                              matlab.ui.control.NumericEditField
        gzEditFieldLabel                matlab.ui.control.Label
        gz                              matlab.ui.control.NumericEditField
        InverseDynamicsButton           matlab.ui.control.Button
        SimulationRunTimesEditFieldLabel  matlab.ui.control.Label
        SimulationRunTime               matlab.ui.control.NumericEditField
        InitialJointDerivativeValuesLabel  matlab.ui.control.Label
        InitDerivativeVals              matlab.ui.control.EditField
        InitialJointAngleValuesLabel    matlab.ui.control.Label
        InitAngleVals                   matlab.ui.control.EditField
        DesiredPositionValuesLabel      matlab.ui.control.Label
        DesiredPositions                matlab.ui.control.EditField
        PDGravityButton                 matlab.ui.control.Button
        NOTEWriteanglesindegreesLabel   matlab.ui.control.Label
        DesiredVelocityValuesLabel      matlab.ui.control.Label
        DesiredVelocities               matlab.ui.control.EditField
        DesiredAccelerationValuesLabel  matlab.ui.control.Label
        DesiredAccel                    matlab.ui.control.EditField
        Q_Plot                          matlab.ui.control.UIAxes
        XYZ_Plot                        matlab.ui.control.UIAxes
        Qd_Plot                         matlab.ui.control.UIAxes
        NOTETextAreaLabel               matlab.ui.control.Label
        NOTETextArea                    matlab.ui.control.TextArea
        MainMenuButton                  matlab.ui.control.Button
        ArmPlotButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        FillRobotInfoApp;
        g=[];
        Robot_R % Robot
        Links={} %Array of Links
    end
    
    methods (Access = public)    
         function updateRobotinfo(app, k, Ixyz, Jm, G, m, r, params, armtype)
             d=params(1);a=params(2);
             alpha=params(3)*pi/180; theta=params(4)*pi/180;
             if armtype==1 %Revolute Joint
                 app.Links{k}=Revolute('a',a,'alpha',alpha,'d',d,'Jm',Jm,'G',G,'I',Ixyz,'m',m,'r',r);
             else %Prismatic Joint
                 app.Links{k}=Prismatic('a',a,'alpha',alpha,'theta',theta,'Jm',Jm,'G',G,'I',Ixyz,'m',m,'r',r);
             end
         end         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ArmPlotButton.Enable = 'off';
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.OKButton.Enable = 'off';
            n=app.Howmanylinks.Value;
            for k=1:n
                app.FillRobotInfoApp=RobotInfo_noDH(app, k);
                waitfor(app.FillRobotInfoApp);
            end
            app.OKButton.Enable = 'on';
            for k=1:n
                x(k)=app.Links{k};
            end
            app.Robot_R=SerialLink(x);
            app.Robot_R.fast=0;
            app.ArmPlotButton.Enable = 'on';
        end

        % Button pushed function: UpdateGravityVectorButton
        function UpdateGravityVectorButtonPushed(app, event)
            app.Robot_R.gravity=[app.gx.Value, app.gy.Value, app.gz.Value];
        end

        % Button pushed function: InverseDynamicsButton
        function InverseDynamicsButtonPushed(app, event)
            cla(app.Q_Plot);cla(app.XYZ_Plot);cla(app.Qd_Plot);
            dt=2e-2;
            T=floor(app.SimulationRunTime.Value/dt);
            n=app.Robot_R.n;
            init_angle_vals=(pi/180)*str2num(app.InitAngleVals.Value);
            init_derivative_vals=(pi/180)*str2num(app.InitDerivativeVals.Value);
            xp=str2num(app.DesiredPositions.Value);xp=xp';
            xv=str2num(app.DesiredVelocities.Value);xv=xv';
            xa=str2num(app.DesiredAccel.Value);xa=xa';
            q(1,:)=init_angle_vals;qd(1,:)=init_derivative_vals;
            Kd=100*diag(ones(1,1));Kp=10*diag(ones(1,1));
            for i=1:T
                J=app.Robot_R.jacob0(q(i,:));
                %J=J(1:3,:);
                
                Jd=app.Robot_R.jacob_dot(q(i,:),qd(i,:));
                
                xe=transl(app.Robot_R.fkine(q(i,:)));xek(i,:)=xe';
                J_qd=J*qd(i,:)';
                xt=xp-xe;xtd=xv-J_qd(1:3);
                s=Kp*xt+Kd*xtd+xa-Jd(1:3);
                Jinv=J\eye(max(size(J)));%calculate inverse (pseudo-inverse automatically calcuated if rank-deficient).
                %y=Jinv*s;
                y=Jinv(:,1:3)*s;
                
                Bq=app.Robot_R.inertia(q(i,:));
                n_q_qd=app.Robot_R.coriolis(q(i,:),qd(i,:))*qd(i,:)'+app.Robot_R.gravjac(q(i,:))';
                u=Bq*y+n_q_qd;
                
                qdd=inv(Bq)*(u-n_q_qd);
                q(i+1,:)=q(i,:)+qd(i,:)*dt;
                qd(i+1,:)=qd(i,:)+qdd'*dt;
            end
            t_plot1=0:dt:(app.SimulationRunTime.Value-dt);t_plot2=0:dt:(app.SimulationRunTime.Value);
            hold(app.XYZ_Plot, "on");
            plot(app.XYZ_Plot, t_plot1, xek(:,1));plot(app.XYZ_Plot, t_plot1, xek(:,2));plot(app.XYZ_Plot, t_plot1, xek(:,3));
            title(app.XYZ_Plot, 'X, Y, and Z-axis plot vs. Time');legend(app.XYZ_Plot,'X','Y','Z');
            hold(app.XYZ_Plot, "off");
            hold(app.Q_Plot, "on");hold(app.Qd_Plot, "on");
            for j=1:app.Robot_R.n
                plot(app.Q_Plot, t_plot2, q(:,j),'DisplayName',['q' num2str(j)]);
                plot(app.Qd_Plot, t_plot2, qd(:,j));
            end
            title(app.Q_Plot, 'Q vs. Time');legend(app.Q_Plot,'show');
            title(app.Qd_Plot, 'Qd vs. Time');
            hold(app.Q_Plot, "off");hold(app.Qd_Plot, "off");
        end

        % Button pushed function: PDGravityButton
        function PDGravityButtonPushed(app, event)
            cla(app.Q_Plot);cla(app.XYZ_Plot);cla(app.Qd_Plot);
            dt=2e-2;
            T=floor(app.SimulationRunTime.Value/dt);
            n=app.Robot_R.n;
            init_angle_vals=(pi/180)*str2num(app.InitAngleVals.Value);
            init_derivative_vals=(pi/180)*str2num(app.InitDerivativeVals.Value);
            xp=str2num(app.DesiredPositions.Value);xp=xp';
            q(1,:)=init_angle_vals;qd(1,:)=init_derivative_vals;
            Kd=10*diag(ones(1,1));Kp=10*diag(ones(1,1));
            for i=1:T
                J=app.Robot_R.jacob0(q(i,:));
                xe=transl(app.Robot_R.fkine(q(i,:)));xek(i,:)=xe';
                xt=xp-xe;
                J_qd=J*qd(i,:)';
                s=Kp*xt-Kd*J_qd(1:3);
                
                J=J(1:3,:);
                u=J'*s+app.Robot_R.gravjac(q(i,:))';
                
                Bq=app.Robot_R.inertia(q(i,:));
                n_q_qd=app.Robot_R.coriolis(q(i,:),qd(i,:))*qd(i,:)'+app.Robot_R.gravjac(q(i,:))';
                
                qdd=inv(Bq)*(u-n_q_qd);
                q(i+1,:)=q(i,:)+qd(i,:)*dt;
                qd(i+1,:)=qd(i,:)+qdd'*dt;
            end
            t_plot1=0:dt:(app.SimulationRunTime.Value-dt);t_plot2=0:dt:(app.SimulationRunTime.Value);
            hold(app.XYZ_Plot, "on");
            plot(app.XYZ_Plot, t_plot1, xek(:,1));plot(app.XYZ_Plot, t_plot1, xek(:,2));plot(app.XYZ_Plot, t_plot1, xek(:,3));
            title(app.XYZ_Plot, 'X, Y, and Z-axis plot vs. Time');legend(app.XYZ_Plot,'X','Y','Z');
            hold(app.XYZ_Plot, "off");
            hold(app.Q_Plot, "on");hold(app.Qd_Plot, "on");
            for j=1:app.Robot_R.n
                plot(app.Q_Plot, t_plot2, q(:,j),'DisplayName',['q' num2str(j)]);
                plot(app.Qd_Plot, t_plot2, qd(:,j));
            end
            title(app.Q_Plot, 'Q vs. Time');legend(app.Q_Plot,'show');
            title(app.Qd_Plot, 'Qd vs. Time');
            hold(app.Q_Plot, "off");hold(app.Qd_Plot, "off");
        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            MainScreen;
            delete(app.FillRobotInfoApp);
            delete(app);
        end

        % Button pushed function: ArmPlotButton
        function ArmPlotButtonPushed(app, event)
            teach(app.Robot_R);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotionControl = uifigure('Visible', 'off');
            app.MotionControl.Name = 'Motion Control';
            app.MotionControlLabel = uilabel(app.MotionControl);
            app.MotionControlLabel.Text = 'Motion Control';
            app.OKButton = uibutton(app.MotionControl, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.HowmanylinkswillbeinyourrobotEditFieldLabel = uilabel(app.MotionControl);
            app.HowmanylinkswillbeinyourrobotEditFieldLabel.Text = 'How many links will be in your robot?';
            app.Howmanylinks = uieditfield(app.MotionControl, 'numeric');
            app.GravVectorText = uilabel(app.MotionControl);
            app.GravVectorText.Text = 'Gravity vector for links :';
            app.UpdateGravityVectorButton = uibutton(app.MotionControl, 'push');
            app.UpdateGravityVectorButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGravityVectorButtonPushed, true);
            app.UpdateGravityVectorButton.Text = 'Update Gravity Vector';
            app.gxEditFieldLabel = uilabel(app.MotionControl);
            app.gxEditFieldLabel.Text = 'gx';
            app.gx = uieditfield(app.MotionControl, 'numeric');
            app.gyEditFieldLabel = uilabel(app.MotionControl);
            app.gyEditFieldLabel.Text = 'gy';
            app.gy = uieditfield(app.MotionControl, 'numeric');
            app.gzEditFieldLabel = uilabel(app.MotionControl);
            app.gzEditFieldLabel.Text = 'gz';
            app.gz = uieditfield(app.MotionControl, 'numeric');
            app.gz.Value = 9.81;
            app.InverseDynamicsButton = uibutton(app.MotionControl, 'push');
            app.InverseDynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseDynamicsButtonPushed, true);
            app.InverseDynamicsButton.Text = 'Inverse Dynamics';
            app.SimulationRunTimesEditFieldLabel = uilabel(app.MotionControl);
            app.SimulationRunTimesEditFieldLabel.Text = 'Simulation Run Time (s):';
            app.SimulationRunTime = uieditfield(app.MotionControl, 'numeric');
            app.SimulationRunTime.Limits = [0 Inf];
            app.InitialJointDerivativeValuesLabel = uilabel(app.MotionControl);
            app.InitialJointDerivativeValuesLabel.Text = 'Initial Joint Derivative Values:';
            app.InitDerivativeVals = uieditfield(app.MotionControl, 'text');
            app.InitialJointAngleValuesLabel = uilabel(app.MotionControl);
            app.InitialJointAngleValuesLabel.Text = 'Initial Joint Angle Values:';
            app.InitAngleVals = uieditfield(app.MotionControl, 'text');
            app.DesiredPositionValuesLabel = uilabel(app.MotionControl);
            app.DesiredPositionValuesLabel.Text = 'Desired Position Values:';
            app.DesiredPositions = uieditfield(app.MotionControl, 'text');
            app.PDGravityButton = uibutton(app.MotionControl, 'push');
            app.PDGravityButton.ButtonPushedFcn = createCallbackFcn(app, @PDGravityButtonPushed, true);
            app.PDGravityButton.Text = 'PD/Gravity';
            app.NOTEWriteanglesindegreesLabel = uilabel(app.MotionControl);
            app.NOTEWriteanglesindegreesLabel.Text = {'NOTE: Velocity and Acceleration values only '; 'required for Inverse Dynamics. Write [0 0 0]'; 'if not being used. '};
            app.DesiredVelocityValuesLabel = uilabel(app.MotionControl);
            app.DesiredVelocityValuesLabel.Text = 'Desired Velocity Values:';
            app.DesiredVelocities = uieditfield(app.MotionControl, 'text');
            app.DesiredAccelerationValuesLabel = uilabel(app.MotionControl);
            app.DesiredAccelerationValuesLabel.Text = 'Desired Acceleration Values:';
            app.DesiredAccel = uieditfield(app.MotionControl, 'text');
            app.Q_Plot = uiaxes(app.MotionControl);
            title(app.Q_Plot, 'Title')
            xlabel(app.Q_Plot, 'X')
            ylabel(app.Q_Plot, 'Y')
            app.XYZ_Plot = uiaxes(app.MotionControl);
            title(app.XYZ_Plot, 'Title')
            xlabel(app.XYZ_Plot, 'X')
            ylabel(app.XYZ_Plot, 'Y')
            app.Qd_Plot = uiaxes(app.MotionControl);
            title(app.Qd_Plot, 'Title')
            xlabel(app.Qd_Plot, 'X')
            ylabel(app.Qd_Plot, 'Y')
            app.NOTETextAreaLabel = uilabel(app.MotionControl);
            app.NOTETextAreaLabel.Text = 'NOTE:';
            app.NOTETextArea = uitextarea(app.MotionControl);
            app.NOTETextArea.Value = {'Please enter each of the Initial Joint Angle and Derivative Values in degrees (as well as the Position, Velocity and Acceleration values) on the right separated by spaces and using square brackets around the outside. '; ''; 'For Example: '; '[0 0 0]'; '[30 60 90]'; 'etc.'; ''; 'Please note that for joint angles/derivatives, please write only as many elements as there are links (for example, if there are 2 links, write only 2 values; write 3 values for 3 links etc.).'; ''; 'Also, write exactly 3 values each for Position, Velocity, and Acceleration, as they represent the values along the x, y and z-axis respectively.'};
            app.MainMenuButton = uibutton(app.MotionControl, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            app.ArmPlotButton = uibutton(app.MotionControl, 'push');
            app.ArmPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ArmPlotButtonPushed, true);
            app.ArmPlotButton.Text = 'Robotic Arm Plot';
            % Show the figure after all components are created
            app.MotionControl.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controls_noDH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotionControl)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotionControl)
        end
    end
end