classdef Dynamics_noDH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManipulatorDynamics        matlab.ui.Figure
        ManipulatorDynamicsLabel   matlab.ui.control.Label
        MainMenuButton             matlab.ui.control.Button
        HowmanylinkswillbeinyourrobotEditFieldLabel  matlab.ui.control.Label
        Howmanylinks               matlab.ui.control.NumericEditField
        OKButton                   matlab.ui.control.Button
        GravVectorText             matlab.ui.control.Label
        gxEditFieldLabel           matlab.ui.control.Label
        gx                         matlab.ui.control.NumericEditField
        gyEditFieldLabel           matlab.ui.control.Label
        gy                         matlab.ui.control.NumericEditField
        gzEditFieldLabel           matlab.ui.control.Label
        gz                         matlab.ui.control.NumericEditField
        UpdateGravityVectorButton  matlab.ui.control.Button
        AnglesPlot                 matlab.ui.control.UIAxes
        B_matrix                   matlab.ui.control.Table
        C_matrix                   matlab.ui.control.Table
        G_matrix                   matlab.ui.control.Table
        EqnDispClick               matlab.ui.control.Button
        DerivativesPlot            matlab.ui.control.UIAxes
        Image                      matlab.ui.control.Image
        ManipulatorDynamicsEquationLabel  matlab.ui.control.Label
        SimulationRunTimesEditFieldLabel  matlab.ui.control.Label
        SimulationRunTime          matlab.ui.control.NumericEditField
        RunButton                  matlab.ui.control.Button
        InitialJointDerivativeValuesindegreesLabel  matlab.ui.control.Label
        InitDerivativeVals         matlab.ui.control.EditField
        InitialJointAngleValuesindegreesLabel  matlab.ui.control.Label
        InitAngleVals              matlab.ui.control.EditField
        TorqueFunctionsLabel       matlab.ui.control.Label
        Functions                  matlab.ui.control.EditField
        NOTETextAreaLabel          matlab.ui.control.Label
        NOTETextArea               matlab.ui.control.TextArea
        ArmPlotButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        FillRobotInfoApp;
        g=[];
        Robot_R % Robot
        Links={} %Array of Links
    end
    
     methods (Access = public)    
         function updateRobotinfo(app, k, Ixyz, Jm, G, m, r, params, armtype)
             d=params(1);a=params(2);
             alpha=params(3)*pi/180; theta=params(4)*pi/180;
             if armtype==1 %Revolute Joint
                 app.Links{k}=Revolute('a',a,'alpha',alpha,'d',d,'Jm',Jm,'G',G,'I',Ixyz,'m',m,'r',r);
             else %Prismatic Joint
                 app.Links{k}=Prismatic('a',a,'alpha',alpha,'theta',theta,'Jm',Jm,'G',G,'I',Ixyz,'m',m,'r',r);
             end
         end         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run '.\rvctools'\startup_rvc.m;
            app.ArmPlotButton.Enable = 'off';
        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            MainScreen;
            delete(app.FillRobotInfoApp);
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.OKButton.Enable = 'off';
            n=app.Howmanylinks.Value;
            for k=1:n
                app.FillRobotInfoApp=RobotInfo_noDH(app, k);
                waitfor(app.FillRobotInfoApp);
            end
            app.OKButton.Enable = 'on';
            for k=1:n
                x(k)=app.Links{k};
            end
            app.Robot_R=SerialLink(x);
            app.Robot_R.fast=0;
            app.ArmPlotButton.Enable = 'on';
        end

        % Button pushed function: UpdateGravityVectorButton
        function UpdateGravityVectorButtonPushed(app, event)
            app.Robot_R.gravity=[app.gx.Value, app.gy.Value, app.gz.Value];
        end

        % Button pushed function: EqnDispClick
        function EqnDispClickButtonPushed(app, event)
            n=app.Howmanylinks.Value;
            syms q [1 n];
            syms qd [1 n];
            
            M=simplify(app.Robot_R.inertia(q));
            C=simplify(app.Robot_R.coriolis(q,qd));
            G=simplify(app.Robot_R.gravjac(q));
            
            app.B_matrix.Data=string(M);
            app.C_matrix.Data=string(C);
            app.G_matrix.Data=string(G);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            fnctn_str=app.Functions.Value;
            init_angle_vals=(pi/180)*str2num(app.InitAngleVals.Value);
            init_derivative_vals=(pi/180)*str2num(app.InitDerivativeVals.Value);
            tf=app.SimulationRunTime.Value;
            num_of_links=app.Robot_R.n;
            fnctn_str=inline(fnctn_str,'t');
            trqfctn=@(Robot_R,t,q,qd) fnctn_str(t);
            [t,q,qd]=app.Robot_R.fdyn(tf,trqfctn,init_angle_vals,init_derivative_vals);
            hold(app.AnglesPlot,"on");hold(app.DerivativesPlot,"on");
            for j=1:num_of_links
                plot(app.AnglesPlot,t,q(:,j),'DisplayName',['q' num2str(j)]);
                plot(app.DerivativesPlot,t,qd(:,j));
            end
            title(app.AnglesPlot,'Joint Positions');legend(app.AnglesPlot,'show');
            title(app.DerivativesPlot,'Joint Velocities');
            hold(app.AnglesPlot,"off");hold(app.DerivativesPlot,"off");
        end

        % Button pushed function: ArmPlotButton
        function ArmPlotButtonPushed(app, event)
            teach(app.Robot_R);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManipulatorDynamics = uifigure('Visible', 'off');
            app.ManipulatorDynamics.Name = 'Manipulator Dynamics';
            app.ManipulatorDynamicsLabel = uilabel(app.ManipulatorDynamics);
            app.ManipulatorDynamicsLabel.Text = 'Manipulator Dynamics';
            app.MainMenuButton = uibutton(app.ManipulatorDynamics, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            app.HowmanylinkswillbeinyourrobotEditFieldLabel = uilabel(app.ManipulatorDynamics);
            app.HowmanylinkswillbeinyourrobotEditFieldLabel.Text = 'How many links will be in your robot?';
            app.Howmanylinks = uieditfield(app.ManipulatorDynamics, 'numeric');
            app.OKButton = uibutton(app.ManipulatorDynamics, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.GravVectorText = uilabel(app.ManipulatorDynamics);
            app.GravVectorText.Text = 'Gravity vector for links :';
            app.gxEditFieldLabel = uilabel(app.ManipulatorDynamics);
            app.gxEditFieldLabel.Text = 'gx';
            app.gx = uieditfield(app.ManipulatorDynamics, 'numeric');
            app.gyEditFieldLabel = uilabel(app.ManipulatorDynamics);
            app.gyEditFieldLabel.Text = 'gy';
            app.gy = uieditfield(app.ManipulatorDynamics, 'numeric');
            app.gzEditFieldLabel = uilabel(app.ManipulatorDynamics);
            app.gzEditFieldLabel.Text = 'gz';
            app.gz = uieditfield(app.ManipulatorDynamics, 'numeric');
            app.gz.Value = 9.81;
            app.UpdateGravityVectorButton = uibutton(app.ManipulatorDynamics, 'push');
            app.UpdateGravityVectorButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGravityVectorButtonPushed, true);
            app.UpdateGravityVectorButton.Text = 'Update Gravity Vector';
            app.AnglesPlot = uiaxes(app.ManipulatorDynamics);
            title(app.AnglesPlot, 'Title')
            xlabel(app.AnglesPlot, 'X')
            ylabel(app.AnglesPlot, 'Y')
            app.B_matrix = uitable(app.ManipulatorDynamics);
            app.B_matrix.ColumnName = {'Matrix B'};
            app.B_matrix.RowName = {};
            app.C_matrix = uitable(app.ManipulatorDynamics);
            app.C_matrix.ColumnName = {'Matrix C'};
            app.C_matrix.RowName = {};
            app.G_matrix = uitable(app.ManipulatorDynamics);
            app.G_matrix.ColumnName = {'Matrix g'};
            app.G_matrix.RowName = {};
            app.EqnDispClick = uibutton(app.ManipulatorDynamics, 'push');
            app.EqnDispClick.ButtonPushedFcn = createCallbackFcn(app, @EqnDispClickButtonPushed, true);
            app.EqnDispClick.Text = 'Manipulator Dynamics Equation Result';
            app.DerivativesPlot = uiaxes(app.ManipulatorDynamics);
            title(app.DerivativesPlot, 'Title')
            xlabel(app.DerivativesPlot, 'X')
            ylabel(app.DerivativesPlot, 'Y')
            app.Image = uiimage(app.ManipulatorDynamics);
            app.Image.ImageSource = 'Equation.PNG';
            app.ManipulatorDynamicsEquationLabel = uilabel(app.ManipulatorDynamics);
            app.ManipulatorDynamicsEquationLabel.Text = 'Manipulator Dynamics Equation:';
            app.SimulationRunTimesEditFieldLabel = uilabel(app.ManipulatorDynamics);
            app.SimulationRunTimesEditFieldLabel.Text = 'Simulation Run Time (s):';
            app.SimulationRunTime = uieditfield(app.ManipulatorDynamics, 'numeric');
            app.SimulationRunTime.Limits = [0 Inf];
            app.RunButton = uibutton(app.ManipulatorDynamics, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.InitialJointDerivativeValuesindegreesLabel = uilabel(app.ManipulatorDynamics);
            app.InitialJointDerivativeValuesindegreesLabel.Text = 'Initial Joint Derivative Values (in degrees):';
            app.InitDerivativeVals = uieditfield(app.ManipulatorDynamics, 'text');
            app.InitialJointAngleValuesindegreesLabel = uilabel(app.ManipulatorDynamics);
            app.InitialJointAngleValuesindegreesLabel.Text = 'Initial Joint Angle Values (in degrees):';
            app.InitAngleVals = uieditfield(app.ManipulatorDynamics, 'text');
            app.TorqueFunctionsLabel = uilabel(app.ManipulatorDynamics);
            app.TorqueFunctionsLabel.Text = 'Torque Function(s):';
            app.Functions = uieditfield(app.ManipulatorDynamics, 'text');
            app.NOTETextAreaLabel = uilabel(app.ManipulatorDynamics);
            app.NOTETextAreaLabel.Text = 'NOTE:';
            app.NOTETextArea = uitextarea(app.ManipulatorDynamics);
            app.NOTETextArea.Value = {'Please enter each of the Functions and Initial Joint Angle and Derivative Values (as a function of time t) below separated by spaces and using square brackets around the outside. '; ''; 'For Example: '; '[t sin(t)] (for function) '; '[0 0] (for values)'; ''; 'Please only enter as many functions and values in each section as there are links.'};
            app.ArmPlotButton = uibutton(app.ManipulatorDynamics, 'push');
            app.ArmPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ArmPlotButtonPushed, true);
            app.ArmPlotButton.Text = 'Robotic Arm Plot';
            % Show the figure after all components are created
            app.ManipulatorDynamics.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dynamics_noDH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManipulatorDynamics)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManipulatorDynamics)
        end
    end
end