classdef MainScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RoboticGUI                  matlab.ui.Figure
        RoboticToolboxLabel         matlab.ui.control.Label
        WhatisDHParametersLabel     matlab.ui.control.Label
        WhatisDHParametersTextArea  matlab.ui.control.TextArea
        AreyoufamiliarwithDHParametersButtonGroup  matlab.ui.container.ButtonGroup
        YesButton                   matlab.ui.control.ToggleButton
        NoButton                    matlab.ui.control.ToggleButton
        WhichtopicwouldyouliketoexploreDropDownLabel  matlab.ui.control.Label
        WhichtopicwouldyouliketoexploreDropDown  matlab.ui.control.DropDown
        AdityaRamani1217146086AksheshkumarShahLabel  matlab.ui.control.Label
        GoButton                    matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run '.\rvctools'\startup_rvc.m;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            switch app.WhichtopicwouldyouliketoexploreDropDown.Value                
                case 'Dynamics'
                    if app.YesButton.Value == true
                        Dynamics;
                    else
                        Dynamics_noDH;
                    end
                case 'Controls'
                    if app.YesButton.Value == true
                        Controls;
                    else
                        Controls_noDH;
                    end
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RoboticGUI = uifigure('Visible', 'off');
            app.RoboticGUI.Name = 'Robotic Toolbox GUI';
            app.RoboticToolboxLabel = uilabel(app.RoboticGUI);
            app.RoboticToolboxLabel.Text = 'Robotic Toolbox';
            app.WhatisDHParametersLabel = uilabel(app.RoboticGUI);
            app.WhatisDHParametersLabel.Text = {'What is DH'; 'Parameters?'};
            app.WhatisDHParametersTextArea = uitextarea(app.RoboticGUI);
            app.WhatisDHParametersTextArea.Value = {'The Denavit-Hartenberg (DH) Parameter consist of 4 parameters:'; ''; '- alpha: angle of rotation along x_i to bring z_(i-1) to z_i.'; '- theta: angle of rotation along z_i to bring x_(i-1) to x_i.'; '- length a: length required to traverse along x-axis to go from x_(i-1) to x_i.'; '- length d: length required to traverse along z-axis to go from z_(i-1) to z_i.'};
            app.AreyoufamiliarwithDHParametersButtonGroup = uibuttongroup(app.RoboticGUI);
            app.AreyoufamiliarwithDHParametersButtonGroup.Title = 'Are you familiar with DH Parameters?';
            app.YesButton = uitogglebutton(app.AreyoufamiliarwithDHParametersButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uitogglebutton(app.AreyoufamiliarwithDHParametersButtonGroup);
            app.NoButton.Text = 'No';
            app.WhichtopicwouldyouliketoexploreDropDownLabel = uilabel(app.RoboticGUI);
            app.WhichtopicwouldyouliketoexploreDropDownLabel.Text = 'Which topic would you like to explore?';
            app.WhichtopicwouldyouliketoexploreDropDown = uidropdown(app.RoboticGUI);
            app.WhichtopicwouldyouliketoexploreDropDown.Items = {'Dynamics', 'Controls'};
            app.WhichtopicwouldyouliketoexploreDropDown.Value = 'Dynamics';
            app.AdityaRamani1217146086AksheshkumarShahLabel = uilabel(app.RoboticGUI);
            app.AdityaRamani1217146086AksheshkumarShahLabel.Text = {'Omkar Kulkarni                       1217203169'; 'Aditya Ramani                        1217146086'; 'Aksheshkumar Shah              1217506160'; 'Venkatesh Vaidyanathan        1217397597'};
            app.GoButton = uibutton(app.RoboticGUI, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            % Show the figure after all components are created
            app.RoboticGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainScreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RoboticGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RoboticGUI)
        end
    end
end