classdef RobotInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LinkInfo                      matlab.ui.Figure
        RoboticLinkInfo               matlab.ui.control.Label
        TypeofLink                    matlab.ui.container.ButtonGroup
        Revolute                      matlab.ui.control.RadioButton
        Prismatic                     matlab.ui.control.RadioButton
        IxxEditFieldLabel             matlab.ui.control.Label
        Ixx                           matlab.ui.control.NumericEditField
        IxyEditFieldLabel             matlab.ui.control.Label
        Ixy                           matlab.ui.control.NumericEditField
        IxzEditFieldLabel             matlab.ui.control.Label
        Ixz                           matlab.ui.control.NumericEditField
        IyyEditFieldLabel             matlab.ui.control.Label
        Iyy                           matlab.ui.control.NumericEditField
        IyzEditFieldLabel             matlab.ui.control.Label
        Iyz                           matlab.ui.control.NumericEditField
        IzzEditFieldLabel             matlab.ui.control.Label
        Izz                           matlab.ui.control.NumericEditField
        InertiaValuesText             matlab.ui.control.Label
        dEditFieldLabel               matlab.ui.control.Label
        d                             matlab.ui.control.NumericEditField
        aEditFieldLabel               matlab.ui.control.Label
        a                             matlab.ui.control.NumericEditField
        alphaindegreesEditFieldLabel  matlab.ui.control.Label
        alpha                         matlab.ui.control.NumericEditField
        thetaindegreesEditFieldLabel  matlab.ui.control.Label
        theta                         matlab.ui.control.NumericEditField
        LengthsAnglestext             matlab.ui.control.Label
        Note                          matlab.ui.control.Label
        LinkMassLabel                 matlab.ui.control.Label
        LinkMass                      matlab.ui.control.NumericEditField
        JmLabel                       matlab.ui.control.Label
        Jm                            matlab.ui.control.NumericEditField
        GearRatioLabel                matlab.ui.control.Label
        GearRatio                     matlab.ui.control.NumericEditField
        OKButton                      matlab.ui.control.Button
        CGxLabel                      matlab.ui.control.Label
        CGx                           matlab.ui.control.NumericEditField
        CGyLabel                      matlab.ui.control.Label
        CGy                           matlab.ui.control.NumericEditField
        CGzLabel                      matlab.ui.control.Label
        CGz                           matlab.ui.control.NumericEditField
        CGLocations                   matlab.ui.control.Label
        NOTETextAreaLabel             matlab.ui.control.Label
        NOTETextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        CallingApp % Description
        index
        Ixyz
        CG
        LenAngles
        ArmType
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, k)
            run '.\rvctools'\startup_rvc.m;
            app.CallingApp=mainapp;
            app.index.Value=k;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.Ixyz.Value=[app.Ixx.Value -app.Ixy.Value -app.Ixz.Value; -app.Ixy.Value app.Iyy.Value -app.Ixz.Value; -app.Ixy.Value -app.Iyz.Value app.Izz.Value];
            app.CG.Value=[app.CGx.Value app.CGy.Value app.CGz.Value];
            app.LenAngles.Value=[app.d.Value app.a.Value app.alpha.Value app.theta.Value];
            if (app.Revolute.Value)
                app.ArmType=1;
            else
                app.ArmType=2;
            end
            updateRobotinfo(app.CallingApp, app.index.Value, app.Ixyz.Value, app.Jm.Value, app.GearRatio.Value, app.LinkMass.Value, app.CG.Value, app.LenAngles.Value, app.ArmType);
            delete(app);
        end

        % Close request function: LinkInfo
        function LinkInfoCloseRequest(app, event)
            delete(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LinkInfo = uifigure('Visible', 'off');
            app.LinkInfo.Name = 'Robotic Link Info';
            app.LinkInfo.CloseRequestFcn = createCallbackFcn(app, @LinkInfoCloseRequest, true);
            app.RoboticLinkInfo = uilabel(app.LinkInfo);
            app.RoboticLinkInfo.Text = 'Enter Robotic Link Info';
            app.TypeofLink = uibuttongroup(app.LinkInfo);
            app.TypeofLink.Title = 'Type of Joint';
            app.Revolute = uiradiobutton(app.TypeofLink);
            app.Revolute.Text = 'Revolute';
            app.Revolute.Value = true;
            app.Prismatic = uiradiobutton(app.TypeofLink);
            app.Prismatic.Text = 'Prismatic';
            app.IxxEditFieldLabel = uilabel(app.LinkInfo);
            app.IxxEditFieldLabel.Text = 'Ixx';
            app.Ixx = uieditfield(app.LinkInfo, 'numeric');
            app.Ixx.Value = 1;
            app.IxyEditFieldLabel = uilabel(app.LinkInfo);
            app.IxyEditFieldLabel.Text = 'Ixy';
            app.Ixy = uieditfield(app.LinkInfo, 'numeric');
            app.IxzEditFieldLabel = uilabel(app.LinkInfo);
            app.IxzEditFieldLabel.Text = 'Ixz';
            app.Ixz = uieditfield(app.LinkInfo, 'numeric');
            app.IyyEditFieldLabel = uilabel(app.LinkInfo);
            app.IyyEditFieldLabel.Text = 'Iyy';
            app.Iyy = uieditfield(app.LinkInfo, 'numeric');
            app.Iyy.Value = 1;
            app.IyzEditFieldLabel = uilabel(app.LinkInfo);
            app.IyzEditFieldLabel.Text = 'Iyz';
            app.Iyz = uieditfield(app.LinkInfo, 'numeric');
            app.IzzEditFieldLabel = uilabel(app.LinkInfo);
            app.IzzEditFieldLabel.Text = 'Izz';
            app.Izz = uieditfield(app.LinkInfo, 'numeric');
            app.Izz.Value = 1;
            app.InertiaValuesText = uilabel(app.LinkInfo);
            app.InertiaValuesText.Text = 'Inertia Values for Link:';
            app.dEditFieldLabel = uilabel(app.LinkInfo);
            app.dEditFieldLabel.Text = 'd';
            app.d = uieditfield(app.LinkInfo, 'numeric');
            app.aEditFieldLabel = uilabel(app.LinkInfo);
            app.aEditFieldLabel.Text = 'a';
            app.a = uieditfield(app.LinkInfo, 'numeric');
            app.alphaindegreesEditFieldLabel = uilabel(app.LinkInfo);
            app.alphaindegreesEditFieldLabel.Text = 'alpha (in degrees)';
            app.alpha = uieditfield(app.LinkInfo, 'numeric');
            app.thetaindegreesEditFieldLabel = uilabel(app.LinkInfo);
            app.thetaindegreesEditFieldLabel.Text = 'theta (in degrees)';
            app.theta = uieditfield(app.LinkInfo, 'numeric');
            app.LengthsAnglestext = uilabel(app.LinkInfo);
            app.LengthsAnglestext.Text = 'Lengths and Angles:';
            app.Note = uilabel(app.LinkInfo);
            app.Note.Text = {'NOTE: length d is not used for prismatic joints, and '; 'angle theta likewise for revolute joints.'};
            app.LinkMassLabel = uilabel(app.LinkInfo);
            app.LinkMassLabel.Text = 'Link Mass';
            app.LinkMass = uieditfield(app.LinkInfo, 'numeric');
            app.JmLabel = uilabel(app.LinkInfo);
            app.JmLabel.Text = 'Jm';
            app.Jm = uieditfield(app.LinkInfo, 'numeric');
            app.GearRatioLabel = uilabel(app.LinkInfo);
            app.GearRatioLabel.Text = 'Gear Ratio';
            app.GearRatio = uieditfield(app.LinkInfo, 'numeric');
            app.GearRatio.Value = 111;
            app.OKButton = uibutton(app.LinkInfo, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CGxLabel = uilabel(app.LinkInfo);
            app.CGxLabel.Text = 'CGx';
            app.CGx = uieditfield(app.LinkInfo, 'numeric');
            app.CGyLabel = uilabel(app.LinkInfo);
            app.CGyLabel.Text = 'CGy';
            app.CGy = uieditfield(app.LinkInfo, 'numeric');
            app.CGzLabel = uilabel(app.LinkInfo);
            app.CGzLabel.Text = 'CGz';
            app.CGz = uieditfield(app.LinkInfo, 'numeric');
            app.CGLocations = uilabel(app.LinkInfo);
            app.CGLocations.Text = 'Center of Gravity Locations:';
            app.NOTETextAreaLabel = uilabel(app.LinkInfo);
            app.NOTETextAreaLabel.Text = 'NOTE:';
            app.NOTETextArea = uitextarea(app.LinkInfo);
            app.NOTETextArea.Value = {'This app allows you to fill information regarding the robotic link, one link at a time. Pleae click OK after finishing writing info for one link, which causes this app to close before another identical app is opened to allow you to fill out information for the next link. This process is repeated until all links are accounted for.'};
            % Show the figure after all components are created
            app.LinkInfo.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotInfo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LinkInfo)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LinkInfo)
        end
    end
end