classdef RobotInfo_noDH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LinkInfo           matlab.ui.Figure
        RoboticLinkInfo    matlab.ui.control.Label
        TypeofLink         matlab.ui.container.ButtonGroup
        Revolute           matlab.ui.control.RadioButton
        Prismatic          matlab.ui.control.RadioButton
        IxxEditFieldLabel  matlab.ui.control.Label
        Ixx                matlab.ui.control.NumericEditField
        IxyEditFieldLabel  matlab.ui.control.Label
        Ixy                matlab.ui.control.NumericEditField
        IxzEditFieldLabel  matlab.ui.control.Label
        Ixz                matlab.ui.control.NumericEditField
        IyyEditFieldLabel  matlab.ui.control.Label
        Iyy                matlab.ui.control.NumericEditField
        IyzEditFieldLabel  matlab.ui.control.Label
        Iyz                matlab.ui.control.NumericEditField
        IzzEditFieldLabel  matlab.ui.control.Label
        Izz                matlab.ui.control.NumericEditField
        InertiaValuesText  matlab.ui.control.Label
        Note               matlab.ui.control.Label
        LinkMassLabel      matlab.ui.control.Label
        LinkMass           matlab.ui.control.NumericEditField
        JmLabel            matlab.ui.control.Label
        Jm                 matlab.ui.control.NumericEditField
        GearRatioLabel     matlab.ui.control.Label
        GearRatio          matlab.ui.control.NumericEditField
        OKButton           matlab.ui.control.Button
        CGxLabel           matlab.ui.control.Label
        AlongwhichoftheaxisdoesthelinklengthspanDropDownLabel  matlab.ui.control.Label
        AxisChoice         matlab.ui.control.DropDown
        CGx                matlab.ui.control.NumericEditField
        CGyLabel           matlab.ui.control.Label
        CGy                matlab.ui.control.NumericEditField
        CGzLabel           matlab.ui.control.Label
        CGz                matlab.ui.control.NumericEditField
        CGLocations        matlab.ui.control.Label
        NOTETextAreaLabel  matlab.ui.control.Label
        NOTETextArea       matlab.ui.control.TextArea
        Image              matlab.ui.control.Image
        SelectButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        FillRobotAxisInfo
        index
        Ixyz
        CG
        LenAngles
        d
        a
        alpha
        theta
        ArmType
    end
    
    methods (Access = public)
        function updateLenAgles(app, d, a, alpha, theta)
            app.d.Value=d;
            app.a.Value=a;
            app.alpha.Value=alpha;
            app.theta.Value=theta;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, k)
            run '.\rvctools'\startup_rvc.m;
            app.CallingApp=mainapp;
            app.index.Value=k;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.Ixyz.Value=[app.Ixx.Value -app.Ixy.Value -app.Ixz.Value; -app.Ixy.Value app.Iyy.Value -app.Ixz.Value; -app.Ixy.Value -app.Iyz.Value app.Izz.Value];
            app.CG.Value=[app.CGx.Value app.CGy.Value app.CGz.Value];
            %app.LenAngles.Value=[app.d.Value app.a.Value app.alpha.Value app.theta.Value];
            if (app.Revolute.Value)
                app.ArmType=1;
            else
                app.ArmType=2;
            end
            updateRobotinfo(app.CallingApp, app.index.Value, app.Ixyz.Value, app.Jm.Value, app.GearRatio.Value, app.LinkMass.Value, app.CG.Value, app.LenAngles.Value, app.ArmType);
            delete(app);
        end

        % Close request function: LinkInfo
        function LinkInfoCloseRequest(app, event)
            delete(app);            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.SelectButton.Enable = 'off';
            switch app.AxisChoice.Value                
                case 'neither'
                    app.d.Value=0;
                    app.a.Value=0;
                    app.alpha.Value=0;
                    app.theta.Value=0;
                case 'x'
                    choice=1;
                    app.FillRobotAxisInfo=AxisLen(app, choice);
                    waitfor(app.FillRobotAxisInfo);
                case 'y'
                    choice=2;
                    app.FillRobotAxisInfo=AxisLen(app, choice);
                    waitfor(app.FillRobotAxisInfo);
            end
            app.LenAngles.Value=[app.d.Value app.a.Value app.alpha.Value app.theta.Value];
            app.SelectButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LinkInfo = uifigure('Visible', 'off');
            app.LinkInfo.Name = 'Robotic Link Info';
            app.LinkInfo.CloseRequestFcn = createCallbackFcn(app, @LinkInfoCloseRequest, true);
            app.RoboticLinkInfo = uilabel(app.LinkInfo);
            app.RoboticLinkInfo.Text = 'Enter Robotic Link Info';
            app.TypeofLink = uibuttongroup(app.LinkInfo);
            app.TypeofLink.Title = 'Type of Joint';
            app.Revolute = uiradiobutton(app.TypeofLink);
            app.Revolute.Text = 'Revolute';
            app.Revolute.Value = true;
            app.Prismatic = uiradiobutton(app.TypeofLink);
            app.Prismatic.Text = 'Prismatic';
            app.IxxEditFieldLabel = uilabel(app.LinkInfo);
            app.IxxEditFieldLabel.Text = 'Ixx';
            app.Ixx = uieditfield(app.LinkInfo, 'numeric');
            app.Ixx.Value = 1;
            app.IxyEditFieldLabel = uilabel(app.LinkInfo);
            app.IxyEditFieldLabel.Text = 'Ixy';
            app.Ixy = uieditfield(app.LinkInfo, 'numeric');
            app.IxzEditFieldLabel = uilabel(app.LinkInfo);
            app.IxzEditFieldLabel.Text = 'Ixz';
            app.Ixz = uieditfield(app.LinkInfo, 'numeric');
            app.IyyEditFieldLabel = uilabel(app.LinkInfo);
            app.IyyEditFieldLabel.Text = 'Iyy';
            app.Iyy = uieditfield(app.LinkInfo, 'numeric');
            app.Iyy.Value = 1;
            app.IyzEditFieldLabel = uilabel(app.LinkInfo);
            app.IyzEditFieldLabel.Text = 'Iyz';
            app.Iyz = uieditfield(app.LinkInfo, 'numeric');
            app.IzzEditFieldLabel = uilabel(app.LinkInfo);
            app.IzzEditFieldLabel.Text = 'Izz';
            app.Izz = uieditfield(app.LinkInfo, 'numeric');
            app.Izz.Value = 1;
            app.InertiaValuesText = uilabel(app.LinkInfo);
            app.InertiaValuesText.Text = 'Inertia Values for Link:';
            app.Note = uilabel(app.LinkInfo);
            app.Note.Text = {'NOTE: length d is not used for prismatic joints, and '; 'angle theta likewise for revolute joints.'};
            app.LinkMassLabel = uilabel(app.LinkInfo);
            app.LinkMassLabel.Text = 'Link Mass';
            app.LinkMass = uieditfield(app.LinkInfo, 'numeric');
            app.JmLabel = uilabel(app.LinkInfo);
            app.JmLabel.Text = 'Jm';
            app.Jm = uieditfield(app.LinkInfo, 'numeric');
            app.GearRatioLabel = uilabel(app.LinkInfo);
            app.GearRatioLabel.Text = 'Gear Ratio';
            app.GearRatio = uieditfield(app.LinkInfo, 'numeric');
            app.GearRatio.Value = 111;
            app.OKButton = uibutton(app.LinkInfo, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CGxLabel = uilabel(app.LinkInfo);
            app.CGxLabel.Text = 'CGx';
            app.CGx = uieditfield(app.LinkInfo, 'numeric');
            app.CGyLabel = uilabel(app.LinkInfo);
            app.CGyLabel.Text = 'CGy';
            app.CGy = uieditfield(app.LinkInfo, 'numeric');
            app.CGzLabel = uilabel(app.LinkInfo);
            app.CGzLabel.Text = 'CGz';
            app.CGz = uieditfield(app.LinkInfo, 'numeric');
            app.CGLocations = uilabel(app.LinkInfo);
            app.CGLocations.Text = 'Center of Gravity Locations:';
            app.NOTETextAreaLabel = uilabel(app.LinkInfo);
            app.NOTETextAreaLabel.Text = 'NOTE:';
            app.NOTETextArea = uitextarea(app.LinkInfo);
            app.NOTETextArea.Value = {'This app allows you to fill information regarding the robotic link, one link at a time. Please first write the relevant information before selecting the axis (either x, z or neither) that the link spans, entering relevant info regarding twist angle and length (for the selected axis) by clicking the Select button. '; ''; 'Click OK at the bottom once writing info for one link is complete, which causes this app to close before another identical app is opened to allow you to fill out information for the next link. This process is repeated until all links are accounted for.'; ''; 'Note: Clicking ''Neither'' automatically sets the relevant information regarding lengths and twist angles to 0.'};
            app.Image = uiimage(app.LinkInfo);
            app.Image.ImageSource = 'DHParams.png';
            app.AlongwhichoftheaxisdoesthelinklengthspanDropDownLabel = uilabel(app.LinkInfo);
            app.AlongwhichoftheaxisdoesthelinklengthspanDropDownLabel.Text = {'Along which of the local x- or z-axis '; 'does the link length span?'; '(Choose neither if it spans y-axis only)'};
            app.AxisChoice = uidropdown(app.LinkInfo);
            app.AxisChoice.Items = {'x', 'z', 'neither'};
            app.AxisChoice.Value = 'x';
            app.SelectButton = uibutton(app.LinkInfo, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            % Show the figure after all components are created
            app.LinkInfo.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotInfo_noDH(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LinkInfo)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LinkInfo)
        end
    end
end